classdef Final_Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        numoftEditField       matlab.ui.control.NumericEditField
        numoftLabel           matlab.ui.control.Label
        inv_Th3Label          matlab.ui.control.Label
        a3EditField           matlab.ui.control.NumericEditField
        a3EditFieldLabel      matlab.ui.control.Label
        ResultButton          matlab.ui.control.Button
        ChoosewhatthefuckdoyouwantDropDown  matlab.ui.control.DropDown
        ChoosewhatthefuckdoyouwantDropDownLabel  matlab.ui.control.Label
        a2EditField           matlab.ui.control.NumericEditField
        a2EditFieldLabel      matlab.ui.control.Label
        a1EditField           matlab.ui.control.NumericEditField
        a1EditFieldLabel      matlab.ui.control.Label
        inv_Th2Label          matlab.ui.control.Label
        inv_Th1Label          matlab.ui.control.Label
        Poz_ZEditField        matlab.ui.control.NumericEditField
        Poz_ZEditFieldLabel   matlab.ui.control.Label
        Poz_YEditField        matlab.ui.control.NumericEditField
        Poz_YEditFieldLabel   matlab.ui.control.Label
        Poz_XEditField        matlab.ui.control.NumericEditField
        Poz_XEditFieldLabel   matlab.ui.control.Label
        Pos_ZLabel            matlab.ui.control.Label
        Pos_YLabel            matlab.ui.control.Label
        Pos_XLabel            matlab.ui.control.Label
        Theta3EditField       matlab.ui.control.NumericEditField
        Theta3EditFieldLabel  matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.NumericEditField
        Theta2EditFieldLabel  matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta1EditFieldLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
            function visualizeRobot2D(app, x, y, theta1, a1, theta2, a2)
                        % Calculate joint positions based on forward kinematics
                        x2 = a1 * cos(theta1);   % X position of joint 2
                        y2 = a1 * sin(theta1);   % Y position of joint 2
                    
                        % End effector position (x, y) is already calculated
                        
                        % Clear previous plot
                        cla(app.UIAxes2);
                    
                        % Plot the base, links, and joints
                        hold(app.UIAxes2, 'on');
                        plot(app.UIAxes2, [0 x2], [0 y2], 'k', 'LineWidth', 2);  % Link 1
                        plot(app.UIAxes2, [x2 x], [y2 y], 'k', 'LineWidth', 2);  % Link 2
                        plot(app.UIAxes2, 0, 0, 'ko', 'MarkerFaceColor', 'k');  % Base
                        plot(app.UIAxes2, x2, y2, 'ko', 'MarkerFaceColor', 'r'); % Joint 2
                        plot(app.UIAxes2, x, y, 'ko', 'MarkerFaceColor', 'b');   % End Effector
                        hold(app.UIAxes2, 'off');
                    
                        % Axis settings
                        axis(app.UIAxes2, 'equal');
                        xlim(app.UIAxes2, [-a1-a2, a1+a2]);
                        ylim(app.UIAxes2, [-a1-a2, a1+a2]);
            end
        
            function visualizeRobot3D(app, theta1, a1, theta2, a2, theta3, a3)
                % Calculate joint positions based on forward kinematics
                % Forward kinematics for a 3-link 3D robot
                % First Link Transformation Matrix
                T1 = [cos(theta1), -sin(theta1), 0, a1*cos(theta1);
                      sin(theta1), cos(theta1), 0, a1*sin(theta1);
                      0, 0, 1, 0;
                      0, 0, 0, 1];
            
                % Second Link Transformation Matrix
                % Rotation about the y-axis and translation along the new x-axis
                T2 = [cos(theta2), 0, sin(theta2), a2*cos(theta2);
                      0, 1, 0, 0;
                      -sin(theta2), 0, cos(theta2), -a2*sin(theta2);
                      0, 0, 0, 1];
                        
                % Third Link Transformation Matrix
                % Rotation about the x-axis and translation along the new z-axis
                T3 = [cos(theta3), -sin(theta3), 0, a3*cos(theta3);
                     sin(theta3), cos(theta3), 0, a3*sin(theta3);
                     0, 0, 1, 0;
                     0, 0, 0, 1];
                
                 % Combine transformations
                 T = (T1 * T2 * T3);
                 
                % First joint position
                x1 = 0;
                y1 = 0;
                z1 = 0;
            
                % Second joint position
                x2 = a1 * cos(theta1);
                y2 = a1 * sin(theta1);
                z2 = 0;
                
                T1_2 = (T1 * T2)
                
                % Extract joint positions
                x1 = T1(1,4);
                y1 = T1(2,4);
                z1 = T1(3,4);
            
                % Accumulate transformations to find second joint
                T12 = T1 * T2;
                x2 = T12(1,4);
                y2 = T12(2,4);
                z2 = T12(3,4);
            
                % Accumulate to find end effector
                x = T(1,4);
                y = T(2,4);
                z = T(3,4);
            
                % Clear previous plot
                cla(app.UIAxes);
            
                % Plot the base, links, and joints
                hold(app.UIAxes, 'on');
                plot3(app.UIAxes, [0 x1], [0 y1], [0 z1], 'k-', 'LineWidth', 2); % Link 1
                plot3(app.UIAxes, [x1 x2], [y1 y2], [z1 z2], 'r-', 'LineWidth', 2); % Link 2
                plot3(app.UIAxes, [x2 x], [y2 y], [z2 z], 'b-', 'LineWidth', 2); % Link 3
                plot3(app.UIAxes, 0, 0, 0, 'ko', 'MarkerFaceColor', 'k'); % Base
                plot3(app.UIAxes, x1, y1, z1, 'ko', 'MarkerFaceColor', 'r'); % Joint 2
                plot3(app.UIAxes, x2, y2, z2, 'ko', 'MarkerFaceColor', 'b'); % Joint 3
                plot3(app.UIAxes, x, y, z, 'ko', 'MarkerFaceColor', 'g'); % End Effector
                hold(app.UIAxes, 'off');
            
                % Axis settings
                axis(app.UIAxes, 'equal');
                axis(app.UIAxes, [-a1-a2-a3, a1+a2+a3, -a1-a2-a3, a1+a2+a3, -a1-a2-a3, a1+a2+a3]);
                xlabel(app.UIAxes, 'X');
                ylabel(app.UIAxes, 'Y');
                zlabel(app.UIAxes, 'Z');
                grid(app.UIAxes, 'on');
                view(app.UIAxes, 3); % Set the view to 3D
            end


            function [x, y, z] = forwardKinematics(app,theta1, theta2,theta3, a1, a2,a3)
                % Forward kinematics for a 3-link 3D robot
                % First Link Transformation Matrix
                T1 = [cos(theta1), -sin(theta1), 0, a1*cos(theta1);
                     sin(theta1), cos(theta1), 0, a1*sin(theta1);
                     0, 0, 1, 0;
                     0, 0, 0, 1];
            
                % Second Link Transformation Matrix
                % Rotation about the y-axis and translation along the new x-axis
                T2 = [cos(theta2), 0, sin(theta2), a2*cos(theta2);
                      0, 1, 0, 0;
                      -sin(theta2), 0, cos(theta2), -a2*sin(theta2);
                      0, 0, 0, 1];
                        
                 % Third Link Transformation Matrix
                 % Rotation about the x-axis and translation along the new z-axis
                 T3 = [cos(theta3), -sin(theta3), 0, a3*cos(theta3);
                       sin(theta3), cos(theta3), 0, a3*sin(theta3);
                       0, 0, 1, 0;
                       0, 0, 0, 1];
                 
                 % Combine transformations
                 T = (T1 * T2 * T3);
                        
                 % Extract the end-effector position
                 x = T(1, 4);
                 y = T(2, 4);
                 z=  T(3, 4);


            end
       
       
           function [theta1, theta2, theta3] = inverseKinematics(app,x, y, z, a1, a2, a3,num)
                % Check if the point is in the plane where the robot operates
                %if z ~= 0
                %error('The target point is not in the plane where the robot operates.');
                %end
            
                % Check if the point is reachable
            %    d = sqrt(x^2 + y^2+z^2);
            %    if d >= sqrt(a1^2 + a2^2 + a3^2)   % Added check for too close if arms can't fold completely
            %        error('The target point is unreachable.');
            %    end
            
                % Initialize the joint angles
                theta1 = 0;
                theta2 = 0;
                theta3 = 0;
            
                % Set the learning rate
                alpha = 0.01;
            
                % Calculate the initial end effector position and error
                [end_effector, error] = calculateEndEffectorAndError(app,x, y, z, theta1, theta2, theta3, a1, a2, a3);
            
                for i = 1:num
                    % Calculate the gradients of the error with respect to the joint angles
                    [grad1, grad2, grad3] = calculateGradients(app,x, y, z, theta1, theta2, theta3, a1, a2, a3);
            
                    % Update the joint angles
                    theta1 = theta1 - alpha * grad1;
                    theta2 = theta2 - alpha * grad2;
                    theta3 = theta3 - alpha * grad3;
            
                    % Recalculate the end effector position and error using forwardKinematics
                    [end_effector_x, end_effector_y, end_effector_z] = forwardKinematics(app, theta1, theta2, theta3, a1, a2, a3);
                    error = sqrt((x - end_effector_x)^2 + (y - end_effector_y)^2 + (z - end_effector_z)^2);
                end
        end


        function [end_effector, error] = calculateEndEffectorAndError(app,x, y, z, theta1, theta2, theta3, a1, a2, a3)
            % Calculate the end effector position based on the joint angles and the lengths of the links
            [end_effector_x, end_effector_y, end_effector_z] = forwardKinematics(app, theta1, theta2, theta3, a1, a2, a3);
        
            % Create the end effector position vector
            end_effector = [end_effector_x; end_effector_y; end_effector_z];
        
            % Calculate the error as the Euclidean distance between the target position and the end effector position
            error = sqrt((x - end_effector_x)^2 + (y - end_effector_y)^2 + (z - end_effector_z)^2);
        end

        
        function [grad1, grad2, grad3] = calculateGradients(app,x, y, z, theta1, theta2, theta3, a1, a2, a3)
            % Set the perturbation
            delta = 0.0001;
        
            % Calculate the error at the current joint angles
            [~, error] = calculateEndEffectorAndError(app,x, y, z, theta1, theta2, theta3, a1, a2, a3);
        
            % Calculate the error at the perturbed joint angles
            [~, error_perturbed1] = calculateEndEffectorAndError(app,x, y, z, theta1 + delta, theta2, theta3, a1, a2, a3);
            [~, error_perturbed2] = calculateEndEffectorAndError(app,x, y, z, theta1, theta2 + delta, theta3, a1, a2, a3);
            [~, error_perturbed3] = calculateEndEffectorAndError(app,x, y, z, theta1, theta2, theta3 + delta, a1, a2, a3);
        
            % Calculate the gradients using the numerical method
            grad1 = (error_perturbed1 - error) / delta;
            grad2 = (error_perturbed2 - error) / delta;
            grad3 = (error_perturbed3 - error) / delta;
        end


    end
    
    methods (Access = private)
        
        function T = workspace(app,a,alpha,d,theta)
    
            T = [cos(theta) -cos(alpha)*sin(theta) sin(alpha)*sin(theta) a*cos(theta)
                 sin(theta) cos(alpha)*cos(theta) -sin(alpha)*cos(theta) a*sin(theta)
                 0 sin(alpha) cos(alpha) d
                 0 0 0 1];
            
        end
        
        function out = arr2Rad(app,A)
            out = arrayfun(@(angle) deg2rad(angle), A);
        end

    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResultButton
        function Result(app, event)
   switch app.ChoosewhatthefuckdoyouwantDropDown.Value
       case 'inverse'
           
            PX = (app.Poz_XEditField.Value);
            PY = (app.Poz_YEditField.Value);
            PZ = (app.Poz_ZEditField.Value);
        
            a1 =(app.a1EditField.Value);
            a2 =(app.a2EditField.Value);
            a3 =(app.a3EditField.Value);
            
            num_of_t=(app.numoftEditField.Value);
            
            disp(a1);
            disp(a2);
            disp(a3);
            disp(PX);
            disp(PY);
            disp(PZ);
             
        
            [theta1, theta2, theta3] = inverseKinematics(app,PX, PY, PZ, a1, a2, a3,num_of_t);
        
            app.inv_Th1Label.Text = num2str(theta1 * 180 / pi, '%.2f'); % Convert radians to degrees if needed
            app.inv_Th2Label.Text = num2str(theta2 * 180 / pi,'%.2f');
            app.inv_Th3Label.Text = num2str(theta3 * 180 / pi,'%.2f');
        
            % Calculate x, y, z based on the output of inverseKinematics
            [x, y, z] = forwardKinematics(app, theta1, theta2, theta3, a1, a2, a3);
            disp(x);
            disp(y);
            disp(z);
        
        
            visualizeRobot3D(app, theta1, a1, theta2, a2, theta3, a3);


         case 'forwarddd'
             Th_1=(app.Theta1EditField.Value)* pi / 180;
             Th_2=(app.Theta2EditField.Value)* pi / 180;
             Th_3=(app.Theta3EditField.Value)* pi / 180;
                                
                    
             a1 =(app.a1EditField.Value);
             a2 =(app.a2EditField.Value);
             a3 =(app.a3EditField.Value);
                    
             [x, y, z]= forwardKinematics(app,Th_1, Th_2,Th_3, a1, a2,a3);
                    
             app.Pos_XLabel.Text = num2str(x);
             app.Pos_YLabel.Text = num2str(y);
             app.Pos_ZLabel.Text =num2str(z);
                    
             visualizeRobot3D(app, Th_1, a1, Th_2, a2, Th_3, a3)


        case 'Workspace'
            
            % Clear previous plot
            cla(app.UIAxes);
               
            a1 =(app.a1EditField.Value);
            a2 =(app.a2EditField.Value);
            a3 =(app.a3EditField.Value);
            
            % DH parameters inserted manually
            a_1=0;  alpha1=-pi/2;  d1=a1; 
            a_2=a2; alpha2=0;      d2=0;  
            a_3=a3; alpha3=0;      d3=0;
            
            t1_min=0;               t1_max=170*(pi/180);
            t2_min=0;               t2_max=30*(pi/180);
            t3_min=-20*(pi/180);    t3_max=170*(pi/180);
            
            theta1=t1_min + (t1_max - t1_min)*rand(10000,1);
            theta2=t2_min + (t2_max - t2_min)*rand(10000,1);
            theta3=t3_min + (t3_max - t3_min)*rand(10000,1);
           
            %rand: This is a built-in MATLAB function that generates random numbers from a uniform distribution between 0 and 1.
            % N: It specifies the number of random numbers to generate.
            %1: It indicates that the generated random numbers should be arranged as a column vector.
            
           for i=1:10000
                
                A1=workspace(app,a_1,alpha1,d1,theta1(i));
                A2=workspace(app,a_2,alpha2,d2,theta2(i));
                A3=workspace(app,a_3,alpha3,d3,theta3(i));
                
                T=A1*A2*A3;
                
                X=T(1,4);
                Y=T(2,4);
                Z=T(3,4);
                
                plot3(app.UIAxes,X,Y,Z,'.')
                hold(app.UIAxes, 'on')
               
           end
            
   end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.Tag = 'Theta_1';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.Tag = 'Theta_2';
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.Tag = 'Theta_3';
            app.Pos_XLabel = uilabel(app.UIFigure);
            app.Pos_XLabel.Text = 'Pos_X';
            app.Pos_YLabel = uilabel(app.UIFigure);
            app.Pos_YLabel.Text = 'Pos_Y';
            app.Pos_ZLabel = uilabel(app.UIFigure);
            app.Pos_ZLabel.Text = 'Pos_Z';
            app.Poz_XEditFieldLabel = uilabel(app.UIFigure);
            app.Poz_XEditFieldLabel.Text = 'Poz_X';
            app.Poz_XEditField = uieditfield(app.UIFigure, 'numeric');
            app.Poz_YEditFieldLabel = uilabel(app.UIFigure);
            app.Poz_YEditFieldLabel.Text = 'Poz_Y';
            app.Poz_YEditField = uieditfield(app.UIFigure, 'numeric');
            app.Poz_ZEditFieldLabel = uilabel(app.UIFigure);
            app.Poz_ZEditFieldLabel.Text = 'Poz_Z';
            app.Poz_ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.inv_Th1Label = uilabel(app.UIFigure);
            app.inv_Th1Label.Text = 'inv_Th1';
            app.inv_Th2Label = uilabel(app.UIFigure);
            app.inv_Th2Label.Text = 'inv_Th2';
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.UIFigure, 'numeric');
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoosewhatthefuckdoyouwantDropDownLabel = uilabel(app.UIFigure);
            app.ChoosewhatthefuckdoyouwantDropDownLabel.Text = 'Choose what the fuck do you want';
            app.ChoosewhatthefuckdoyouwantDropDown = uidropdown(app.UIFigure);
            app.ChoosewhatthefuckdoyouwantDropDown.Items = {'forwarddd', 'inverse', 'Workspace'};
            app.ChoosewhatthefuckdoyouwantDropDown.Value = 'forwarddd';
            app.ResultButton = uibutton(app.UIFigure, 'push');
            app.ResultButton.ButtonPushedFcn = createCallbackFcn(app, @Result, true);
            app.ResultButton.Text = 'Result ';
            app.a3EditFieldLabel = uilabel(app.UIFigure);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.UIFigure, 'numeric');
            app.inv_Th3Label = uilabel(app.UIFigure);
            app.inv_Th3Label.Text = 'inv_Th3';
            app.numoftLabel = uilabel(app.UIFigure);
            app.numoftLabel.Text = 'num of t';
            app.numoftEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end